classdef bmsMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        PrimaryTab                   matlab.ui.container.Tab
        TempTable                    matlab.ui.control.Table
        VoltageTable                 matlab.ui.control.Table
        Panel                        matlab.ui.container.Panel
        ErrorLabel                   matlab.ui.control.Label
        ChargerStatusLabel           matlab.ui.control.Label
        ICMaxTempLabel               matlab.ui.control.Label
        ChargerCurrentLabel          matlab.ui.control.Label
        TSCurrentLabel               matlab.ui.control.Label
        MaxTempLabel                 matlab.ui.control.Label
        MinTempLabel                 matlab.ui.control.Label
        DeltaVoltLabel               matlab.ui.control.Label
        MaxVoltLabel                 matlab.ui.control.Label
        MinVoltLabel                 matlab.ui.control.Label
        AvgVoltLabel                 matlab.ui.control.Label
        PackVoltLabel                matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
        LogCSVCheckBox               matlab.ui.control.CheckBox
        FloatCheckBox                matlab.ui.control.CheckBox
        BalanceCheckBox              matlab.ui.control.CheckBox
        ChargeCurrentFieldEditField  matlab.ui.control.NumericEditField
        ChargeCurrentFieldEditFieldLabel  matlab.ui.control.Label
        BaudDropdownDropDown         matlab.ui.control.DropDown
        BaudDropdownDropDownLabel    matlab.ui.control.Label
        PortField                    matlab.ui.control.EditField
        PortFieldEditFieldLabel      matlab.ui.control.Label
        ChargeButton                 matlab.ui.control.StateButton
        ConnectButton                matlab.ui.control.StateButton
        SummaryTab                   matlab.ui.container.Tab
        ICTempTable                  matlab.ui.control.Table
        SegmentTable                 matlab.ui.control.Table
    end

    
    properties (Access = private)
      ser                 % serial port
    readTimer           % timer για ανάγνωση CAN
    guiTimer            % timer για ενημέρωση GUI

    connected logical = false

    VOLTAGES    double   % 12x12 cell voltages
    TEMPS       double   % 12x12 temperatures
    MAXICTemps  double   % 12 IC temp values

    ivtCurrent double = 0
    chargerCurrent double = 0
    chargerStatusFlag double = -1
    chargerConnected logical = false

    chargingState double = 0   % 0=OFF, 1=ON
    strHex char = '64'         % default current HEX

    TEMP_SENSOR_MAP = [1 2 3 4 5 6 7 8 9 11];
    end
    
      % App initialization
    methods (Access = private)

        function startupFcn(app)
            % ===== Αρχικοποίηση πινάκων BMS =====
            app.VOLTAGES   = -1  * ones(12,12);   % άγνωστες τάσεις
            app.TEMPS      = -40 * ones(12,12);   % αρχικές θερμοκρασίες
            app.MAXICTemps = zeros(1,12);         % 12 IC temps

            % ===== Κατάσταση σύνδεσης / φορτιστή =====
            app.connected         = false;
            app.chargerConnected  = false;
            app.chargingState     = 0;            % 0 = OFF
            app.ivtCurrent        = 0;
            app.chargerCurrent    = 0;
            app.chargerStatusFlag = -1;
            app.strHex            = '64';         % default hex

            % ===== Αρχικά μηνύματα GUI =====
            app.StatusLabel.Text = "Disconnected";
            app.ErrorLabel.Text  = "";

            % ===== Γέμισε τα tables με αρχικά δεδομένα =====
            app.VoltageTable.Data = app.VOLTAGES;
            app.TempTable.Data    = app.TEMPS;

            % Summary tables (απλά 0 προς το παρόν)
            app.SegmentTable.Data = zeros(1,12);
            app.ICTempTable.Data  = zeros(1,12);
            app.ConnectButton.ValueChangedFcn = @(src,event) ConnectButtonValueChanged(app,event);
            app.TempTable.CellEditCallback   = @(src,event) TempTableCellEdit(app,event);

        % ======================================================
        %   TIMERS
        % ======================================================
            app.readTimer = timer( ...
              "ExecutionMode", "fixedSpacing", ...
              "Period", 0.02, ...             % 20ms
              "TimerFcn", @(~,~) serialReadTick(app) ...
      );

           app.guiTimer = timer( ...
              "ExecutionMode", "fixedSpacing", ...
              "Period", 0.10, ...             % 10Hz
              "TimerFcn", @(~,~) guiTick(app) ...
      );


 end
    function startupFcn2(app)
        startupFcn(app);
    end
        
      function serialReadTick(app)
    if ~app.connected
        return;
    end
 
   
    try
        while app.ser.NumBytesAvailable > 0
            line = readline(app.ser);
            line = strtrim(line);

            % Αγνόησε άδειες γραμμές
            if line == ""
                continue
            end

            % ---------------------------
            % ΠΑΡΑΔΕΙΓΜΑ parsing (όπως Python)
            % Μορφή π.χ.: "V,seg,cell,value"
            % ---------------------------
            parts = split(line, ",");
            if numel(parts) < 4
                continue;
            end

            prefix = parts{1};

            switch prefix
                case "V"   % Voltage frame
                    seg  = str2double(parts{2});
                    cell = str2double(parts{3});
                    val  = str2double(parts{4});
                    if seg>=1 && seg<=12 && cell>=1 && cell<=12
                        app.VOLTAGES(seg,cell) = val;
                    end

                case "T"   % Temperature frame
                    seg  = str2double(parts{2});
                    cell = str2double(parts{3});
                    val  = str2double(parts{4});
                    if seg>=1 && seg<=12 && cell>=1 && cell<=12
                        app.TEMPS(seg,cell) = val;
                    end

                case "IC"  % IC max temp
                    ic  = str2double(parts{2});
                    val = str2double(parts{3});
                    if ic>=1 && ic<=12
                        app.MAXICTemps(ic) = val;
                    end
            end
        end

    catch ME
        app.ErrorLabel.Text = ME.message;
    end
      end
      % ======================================================
    %   GUI UPDATE FUNCTION
    % ======================================================
 function guiTick(app)
    try
        % ===== Tables =====
        app.VoltageTable.Data = app.VOLTAGES;
        app.TempTable.Data    = app.TEMPS;

        % ===== Υπολογισμοί =====
        voltAll = app.VOLTAGES(app.VOLTAGES > 0);
        tempAll = app.TEMPS(app.TEMPS > -40);

        if ~isempty(voltAll)
            app.PackVoltLabel.Text = sprintf("Pack: %.2f V", sum(voltAll)/12);
            app.MinVoltLabel.Text  = sprintf("Min: %.3f", min(voltAll));
            app.MaxVoltLabel.Text  = sprintf("Max: %.3f", max(voltAll));
            app.AvgVoltLabel.Text  = sprintf("Avg: %.3f", mean(voltAll));
            app.DeltaVoltLabel.Text = sprintf("Δ: %.3f", max(voltAll)-min(voltAll));
        end

        if ~isempty(tempAll)
            app.MinTempLabel.Text = sprintf("MinT: %.1f", min(tempAll));
            app.MaxTempLabel.Text = sprintf("MaxT: %.1f", max(tempAll));
        end

        % ===== Summary tables =====
        app.SegmentTable.Data = mean(app.VOLTAGES,2,'omitnan')';
        app.ICTempTable.Data  = app.MAXICTemps;

    catch
        % αγνόησε errors GUI
    end
end
% ======================================================
    %   TempTable Cell Edit (αν το χρειαστείς)
    % ====================================================== 
%function TempTableCellEdit(app, event)
           % indices = event.Indices;
          %  newData = event.NewData;
            
       % end

       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Cell edit callback: TempTable
        function TempTableCellEdit(app, event)
          indices = event.Indices;   % [row, col]
    newValue = event.NewData;

    row = indices(1);
    col = indices(2);

    % Αποθήκευση της νέας τιμής στο matrix TEMPS
    if row >= 1 && row <= 12 && col >= 1 && col <= 12
        app.TEMPS(row, col) = newValue;
    end

    % Προαιρετικό: ενημέρωση GUI αμέσως μετά την αλλαγή
    app.TempTable.Data = app.TEMPS; 
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
    
 value = app.ConnectButton.Value;
             if app.ConnectButton.Value == 1

        try
            % -------- ΡΥΘΜΙΣΗ SERIAL PORT --------
            port = strtrim(app.PortField.Value);
            if isempty(port)
                app.StatusLabel.Text = "No COM port";
                app.ConnectButton.Value = 0;
                return;
            end

            % Το CANdapter μιλάει συνήθως στα 9600bps στο UART
            baud_uart = 9600;

            % Άνοιγμα θύρας
            app.ser = serialport(port, baud_uart, "Timeout", 0.1);
            configureTerminator(app.ser, "CR");   % '\r'

            flush(app.ser);   % καθάρισε buffer

            % -------- ΕΝΤΟΛΕΣ ΣΤΟ CANDAPTER --------
            % Stop, enable timestamps
            writeline(app.ser, "C");
            writeline(app.ser, "L1");
            pause(0.2);

            % Επιλογή CAN bitrate από dropdown
            switch app.BaudDropdownDropDown.Value
                case "125kbit/s"
                    can_cmd = "S4";
                case "250kbit/s"
                    can_cmd = "S5";
                case "500kbit/s"
                    can_cmd = "S6";
                case "1000kbit/s"
                    can_cmd = "S8";
                otherwise
                    can_cmd = "S6";   % default 500k
            end

            writeline(app.ser, can_cmd);
            writeline(app.ser, "L1");
            pause(0.2);

            % Open CAN
            writeline(app.ser, "O");
            writeline(app.ser, "L1");
            pause(0.2);

           
            balanceFlag = app.BalanceCheckBox.Value;   % 0 ή 1
            init = sprintf("T1502%s%d1", app.strHex, balanceFlag);
            writeline(app.ser, init);
            writeline(app.ser, "L1");

            % -------- TIMERS --------
            % Read timer: διαβάζει CAN frames
            start(app.readTimer);

            % GUI timer: ανανεώνει οθόνη (αν δεν τον έχεις ήδη ξεκινήσει στο startup)
            if strcmp(app.guiTimer.Running, "off")
                start(app.guiTimer);
            end

            app.connected = true;
            app.StatusLabel.Text = "Connected";

        catch ME
            % Σε σφάλμα γυρνάμε πίσω σε disconnected
            app.connected = false;
            app.StatusLabel.Text = "Serial Error";
            app.ErrorLabel.Text  = ME.message;

            app.ConnectButton.Value = 0;

            % Κλείσε ό,τι άνοιξε
            try
                if ~isempty(app.ser)
                    clear app.ser
                end
            end
        end

    else
        % ==============================
        %  ΑΠΟΣΥΝΔΕΣΗ (κουμπί OFF)
        % ==============================
        app.connected = false;

        % σταμάτα timers
        if strcmp(app.readTimer.Running,"on")
            stop(app.readTimer);
        end

        % (guiTimer μπορεί να μείνει on, αν θες – εγώ θα τον αφήσω όπως είναι)

        % κλείσε serial
        try
            if ~isempty(app.ser)
                clear app.ser
            end
        end

        app.StatusLabel.Text = "Disconnected";
             end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PrimaryTab = uitab(app.TabGroup);
            app.PrimaryTab.Title = 'PrimaryTab';
            app.Panel = uipanel(app.PrimaryTab);
            app.ConnectButton = uibutton(app.Panel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = {'Connect'; ''};
            app.ChargeButton = uibutton(app.Panel, 'state');
            app.ChargeButton.Text = {'Charge'; ''};
            app.PortFieldEditFieldLabel = uilabel(app.Panel);
            app.PortFieldEditFieldLabel.Text = 'PortField';
            app.PortField = uieditfield(app.Panel, 'text');
            app.BaudDropdownDropDownLabel = uilabel(app.Panel);
            app.BaudDropdownDropDownLabel.Text = 'BaudDropdown';
            app.BaudDropdownDropDown = uidropdown(app.Panel);
            app.BaudDropdownDropDown.Items = {'125kbit/s', '250kbit/s', '500kbit/s', '1000kbit/s', ''};
            app.BaudDropdownDropDown.Value = '125kbit/s';
            app.ChargeCurrentFieldEditFieldLabel = uilabel(app.Panel);
            app.ChargeCurrentFieldEditFieldLabel.Text = 'ChargeCurrentField';
            app.ChargeCurrentFieldEditField = uieditfield(app.Panel, 'numeric');
            app.BalanceCheckBox = uicheckbox(app.Panel);
            app.BalanceCheckBox.Text = {'Balance'; ''};
            app.FloatCheckBox = uicheckbox(app.Panel);
            app.FloatCheckBox.Text = 'Float';
            app.LogCSVCheckBox = uicheckbox(app.Panel);
            app.LogCSVCheckBox.Text = 'Log CSV';
            app.StatusLabel = uilabel(app.Panel);
            app.StatusLabel.Text = 'StatusLabel';
            app.PackVoltLabel = uilabel(app.Panel);
            app.PackVoltLabel.Text = 'PackVoltLabel';
            app.AvgVoltLabel = uilabel(app.Panel);
            app.AvgVoltLabel.Text = 'AvgVoltLabel';
            app.MinVoltLabel = uilabel(app.Panel);
            app.MinVoltLabel.Text = 'MinVoltLabel';
            app.MaxVoltLabel = uilabel(app.Panel);
            app.MaxVoltLabel.Text = 'MaxVoltLabel';
            app.DeltaVoltLabel = uilabel(app.Panel);
            app.DeltaVoltLabel.Text = 'DeltaVoltLabel';
            app.MinTempLabel = uilabel(app.Panel);
            app.MinTempLabel.Text = 'MinTempLabel';
            app.MaxTempLabel = uilabel(app.Panel);
            app.MaxTempLabel.Text = 'MaxTempLabel';
            app.TSCurrentLabel = uilabel(app.Panel);
            app.TSCurrentLabel.Text = 'TSCurrentLabel';
            app.ChargerCurrentLabel = uilabel(app.Panel);
            app.ChargerCurrentLabel.Text = 'ChargerCurrentLabel';
            app.ICMaxTempLabel = uilabel(app.Panel);
            app.ICMaxTempLabel.Text = 'ICMaxTempLabel';
            app.ChargerStatusLabel = uilabel(app.Panel);
            app.ChargerStatusLabel.Text = 'ChargerStatusLabel';
            app.ErrorLabel = uilabel(app.Panel);
            app.ErrorLabel.Text = 'ErrorLabel';
            app.VoltageTable = uitable(app.PrimaryTab);
            app.VoltageTable.ColumnName = {'cell1'; 'cell2'; 'cell3'; 'cell4'; 'cell5'; 'cell6'; 'cell7'; 'cell8'; 'cell9'; 'cell10'; 'cell11'; 'cell12'};
            app.VoltageTable.RowName = {'IC1,IC2,IC3,IC4,IC5,IC6,IC7,IC8,IC9,IC10,IC11,IC12'; ''};
            app.TempTable = uitable(app.PrimaryTab);
            app.TempTable.ColumnName = {'Τ1'; 'Τ2'; 'Τ3'; 'Τ4'; 'Τ5'; 'Τ6'; 'Τ7'; 'Τ8'; 'Τ9'; 'Τ10'; 'Τ11'; 'Τ12'};
            app.TempTable.RowName = {'1:12'; ''};
            app.TempTable.CellEditCallback = createCallbackFcn(app, @TempTableCellEdit, true);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'SummaryTab';
            app.SegmentTable = uitable(app.SummaryTab);
            app.SegmentTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; ''; ''; ''; ''; ''; ''; ''; ''};
            app.SegmentTable.RowName = {};
            app.ICTempTable = uitable(app.SummaryTab);
            app.ICTempTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; ''; ''; ''; ''; ''; ''; ''; ''};
            app.ICTempTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bmsMatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end